classdef Vocabularty < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        InputPanel                   matlab.ui.container.Panel
        BrowseButton_1               matlab.ui.control.Button
        FileEditFieldLabel_1         matlab.ui.control.Label
        BrowseButton_2               matlab.ui.control.Button
        FileEditFieldLabel_2         matlab.ui.control.Label
        stFileEditFieldLabel         matlab.ui.control.Label
        FileEditField_1              matlab.ui.control.EditField
        ndFileEditFieldLabel_4       matlab.ui.control.Label
        FileEditField_2              matlab.ui.control.EditField
        CalculateButton              matlab.ui.control.Button
        FileEditFieldLabel_3         matlab.ui.control.Label
        NumberofWordsinFilesPanel    matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        ResultofCalculationPanel     matlab.ui.container.Panel
        NumberofWordsin1stFileLabel  matlab.ui.control.Label
        NumberofWordsin2stFileLabel  matlab.ui.control.Label
        of1stFileinOverlopLabel      matlab.ui.control.Label
        of2ndFileinOverlopLabel      matlab.ui.control.Label
        NumberofCommonWordsLabel     matlab.ui.control.Label
        Label_1                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton_1
        function BrowseButton_1Pushed(app, event)
            [filename, pathname, filterindex] = uigetfile('*.txt', 'Pick a TXT file');
            fullPath = [pathname, filename];
            if ~filename
                return
            elseif filterindex ~= 1
                app.FileEditFieldLabel_1.Text = 'Please select a TXT file.';
            elseif isa(fullPath, 'char') && isfile(fullPath)
                app.FileEditField_1.Value = fullPath;
                app.FileEditFieldLabel_1.Text = '';
            end
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [filename, pathname, filterindex] = uigetfile('*.txt', 'Pick a TXT file');
            fullPath = [pathname, filename];
            if filterindex ~= 1
                app.FileEditFieldLabel_2.Text = 'Please select a TXT file.';
            elseif isa(fullPath, 'char') && isfile(fullPath)
                app.FileEditField_2.Value = fullPath;
                app.FileEditFieldLabel_2.Text = '';
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            fileName1 = app.FileEditField_1.Value;
            fileName2 = app.FileEditField_2.Value;
            if ~isfile(fileName1)
                app.FileEditFieldLabel_1.Text = 'This file does not exist!';
                return
            end
            fileDir = dir(fileName1);
            if fileDir.bytes == 0
                app.FileEditFieldLabel_1.Text = 'This file is empty!';
                return
            end
            if ~isfile(fileName2)
                app.FileEditFieldLabel_2.Text = 'This file does not exist!';
                return
            end
            fileDir = dir(fileName2);
            if fileDir.bytes == 0
                app.FileEditFieldLabel_2.Text = 'This file is empty!';
                return
            end
            app.FileEditFieldLabel_1.Text = '';
            app.FileEditFieldLabel_2.Text = '';
            app.FileEditFieldLabel_3.Text = 'Please Wait ...';
            app.CalculateButton.Enable = false;
            app.BrowseButton_1.Enable = false;
            app.BrowseButton_2.Enable = false;
            app.FileEditField_1.Enable = false;
            app.FileEditField_2.Enable = false;
            pause(0.1)
            
            [numFile1, numFile2, numCommon, percentOverlap1, percentOverlap2] = calcOverlap(fileName1, fileName2);
            app.Label_1.Text = num2str(numFile1);
            app.Label_2.Text = num2str(numFile2);
            app.Label_3.Text = num2str(numCommon);
            app.Label_4.Text = num2str(percentOverlap1, 4);
            app.Label_5.Text = num2str(percentOverlap2, 4);
            
            bar(app.UIAxes, [1 2 3], [numFile1 numCommon numFile2]);
            app.FileEditFieldLabel_3.Text = '';
            app.CalculateButton.Enable = true;
            app.BrowseButton_1.Enable = true;
            app.BrowseButton_2.Enable = true;
            app.FileEditField_1.Enable = true;
            app.FileEditField_2.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.BrowseButton_1 = uibutton(app.InputPanel, 'push');
            app.BrowseButton_1.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_1Pushed, true);
            app.BrowseButton_1.Text = 'Browse...';
            app.FileEditFieldLabel_1 = uilabel(app.InputPanel);
            app.FileEditFieldLabel_1.Text = '';
            app.BrowseButton_2 = uibutton(app.InputPanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse...';
            app.FileEditFieldLabel_2 = uilabel(app.InputPanel);
            app.FileEditFieldLabel_2.Text = '';
            app.stFileEditFieldLabel = uilabel(app.InputPanel);
            app.stFileEditFieldLabel.Text = '1st File';
            app.FileEditField_1 = uieditfield(app.InputPanel, 'text');
            app.ndFileEditFieldLabel_4 = uilabel(app.InputPanel);
            app.ndFileEditFieldLabel_4.Text = '2nd File';
            app.FileEditField_2 = uieditfield(app.InputPanel, 'text');
            app.CalculateButton = uibutton(app.InputPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.FileEditFieldLabel_3 = uilabel(app.InputPanel);
            app.FileEditFieldLabel_3.Text = '';
            app.NumberofWordsinFilesPanel = uipanel(app.UIFigure);
            app.NumberofWordsinFilesPanel.Title = 'Number of Words in Files';
            app.UIAxes = uiaxes(app.NumberofWordsinFilesPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [1 2 3];
            app.UIAxes.XTickLabel = {'File1'; 'Common'; 'File2'};
            app.ResultofCalculationPanel = uipanel(app.UIFigure);
            app.ResultofCalculationPanel.Title = 'Result of Calculation';
            app.NumberofWordsin1stFileLabel = uilabel(app.ResultofCalculationPanel);
            app.NumberofWordsin1stFileLabel.Text = 'Number of Words in 1st File:';
            app.NumberofWordsin2stFileLabel = uilabel(app.ResultofCalculationPanel);
            app.NumberofWordsin2stFileLabel.Text = 'Number of Words in 2st File:';
            app.of1stFileinOverlopLabel = uilabel(app.ResultofCalculationPanel);
            app.of1stFileinOverlopLabel.Text = '% of 1st File in Overlop:';
            app.of2ndFileinOverlopLabel = uilabel(app.ResultofCalculationPanel);
            app.of2ndFileinOverlopLabel.Text = '% of 2nd File in Overlop:';
            app.NumberofCommonWordsLabel = uilabel(app.ResultofCalculationPanel);
            app.NumberofCommonWordsLabel.Text = 'Number of Common Words:';
            app.Label_1 = uilabel(app.ResultofCalculationPanel);
            app.Label_1.Text = '';
            app.Label_2 = uilabel(app.ResultofCalculationPanel);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.ResultofCalculationPanel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.ResultofCalculationPanel);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.ResultofCalculationPanel);
            app.Label_5.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vocabularty

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end